classdef lab7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        HighSlider         matlab.ui.control.Slider
        HighSliderLabel    matlab.ui.control.Label
        BandSlider_3       matlab.ui.control.Slider
        BandSlider_3Label  matlab.ui.control.Label
        BandSlider_2       matlab.ui.control.Slider
        BandSlider_2Label  matlab.ui.control.Label
        BandSlider         matlab.ui.control.Slider
        BandSliderLabel    matlab.ui.control.Label
        LowSlider          matlab.ui.control.Slider
        LowSliderLabel     matlab.ui.control.Label
        StopButton         matlab.ui.control.Button
        PlayButton         matlab.ui.control.Button
        ResetButton        matlab.ui.control.Button
        PlotButton         matlab.ui.control.Button
        LoadButton         matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
             % Open a dialog to select a file
            [filename, filepath] = uigetfile({'*.wav'}, 'Select Audio File');
        
            % Check if a file was selected
            if isequal(filename,0)
                disp('No file selected.')
            else
                % Load the audio data
                [audio, fs] = audioread(fullfile(filepath, filename));
        
                % Plot the waveform
                plot(app.UIAxes, audio);
                title(app.UIAxes, 'Audio Waveform');
                xlabel(app.UIAxes, 'Time');
                ylabel(app.UIAxes, 'Amplitude');
    end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % Plot some example data
    x = linspace(0, 2*pi, 100);
    y = sin(x);
    plot(app.UIAxes, x, y);
    title(app.UIAxes, 'Sine Wave');
    xlabel(app.UIAxes, 'Time');
    ylabel(app.UIAxes, 'Amplitude');
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
             app.LowSlider.Value = 0;
    app.BandSlider.Value = 0.5;
    app.BandSlider_2.Value = 0.5;
    app.BandSlider_3.Value = 0.5;
    app.HighSlider.Value = 1;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Audio')
            xlabel(app.UIAxes, 'Frequency (kHz)')
            ylabel(app.UIAxes, 'Magnitude (dB)')
            zlabel(app.UIAxes, 'Z')
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.LowSliderLabel = uilabel(app.UIFigure);
            app.LowSliderLabel.Text = 'Low';
            app.LowSlider = uislider(app.UIFigure);
            app.BandSliderLabel = uilabel(app.UIFigure);
            app.BandSliderLabel.Text = 'Band';
            app.BandSlider = uislider(app.UIFigure);
            app.BandSlider_2Label = uilabel(app.UIFigure);
            app.BandSlider_2Label.Text = 'Band';
            app.BandSlider_2 = uislider(app.UIFigure);
            app.BandSlider_3Label = uilabel(app.UIFigure);
            app.BandSlider_3Label.Text = 'Band';
            app.BandSlider_3 = uislider(app.UIFigure);
            app.HighSliderLabel = uilabel(app.UIFigure);
            app.HighSliderLabel.Text = 'High';
            app.HighSlider = uislider(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end